classdef IoTDataExplorerForThingSpeakUsingMATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IoTDataExplorerForThingSpeakUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        UpdateButton                matlab.ui.control.Button
        QuitButton                  matlab.ui.control.Button
        StartDateDatePickerLabel    matlab.ui.control.Label
        StartDateDatePicker         matlab.ui.control.DatePicker
        DurationDropDownLabel       matlab.ui.control.Label
        DurationDropDown            matlab.ui.control.DropDown
        CompareLengthDropDownLabel  matlab.ui.control.Label
        CompareLengthDropDown       matlab.ui.control.DropDown
        ChannelIDEditFieldLabel     matlab.ui.control.Label
        ChannelIDEditField          matlab.ui.control.NumericEditField
        ReadAPIKeyEditFieldLabel    matlab.ui.control.Label
        ReadAPIKeyEditField         matlab.ui.control.EditField
        StartHourDropDownLabel      matlab.ui.control.Label
        StartHourDropDown           matlab.ui.control.DropDown
        XEditField_2Label           matlab.ui.control.Label
        plotDuration                matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        plotLengthofComparison      matlab.ui.control.NumericEditField
        RetimeDropDown              matlab.ui.control.DropDown
        RetimeDropDownLabel         matlab.ui.control.Label
        AMPMSwitchLabel             matlab.ui.control.Label
        AMPMSwitch                  matlab.ui.control.Switch
        MinDropDownLabel            matlab.ui.control.Label
        MinDropDown                 matlab.ui.control.DropDown
        OutputtoWorkspaceButton     matlab.ui.control.Button
        F1CheckBox                  matlab.ui.control.CheckBox
        F2CheckBox                  matlab.ui.control.CheckBox
        F3CheckBox                  matlab.ui.control.CheckBox
        F4CheckBox                  matlab.ui.control.CheckBox
        F5CheckBox                  matlab.ui.control.CheckBox
        F6CheckBox                  matlab.ui.control.CheckBox
        F7CheckBox                  matlab.ui.control.CheckBox
        F8CheckBox                  matlab.ui.control.CheckBox
        RightPanel                  matlab.ui.container.Panel
        StatusLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Copyright 2020 - 2020 The MathWorks, Inc.
    properties (Access = public)
        legendLabel1
        legendLabel2
        myChans
    end
    
    methods (Access = public)
        
        function  displayFields=enumerateSelectedFields(app)
            % Create array of the fields for processing
            displayFields = [app.F1CheckBox.Value, app.F2CheckBox.Value,app.F3CheckBox.Value,app.F4CheckBox.Value,app.F5CheckBox.Value,app.F6CheckBox.Value,app.F7CheckBox.Value,app.F8CheckBox.Value];
        end
    end
    
    methods (Access = private)
        
        function [query,queryRecent,queryOld] = buildQueryFromInputs(app)
            %   buildQueryFromInputs   build the querry inputs into a struct.
            %   q = buildQueryFromInputs(app) is a list of inputs needed to
            %   get the appropriate data from ThingSpeak.
            %   The data includes the start and stop date, channel ID and
            %   API keys.
            %
            query = struct();
            queryRecent = struct();
            queryOld = struct();
            app.StatusLabel.Text = 'Status';
            
            query.APIKey = app.ReadAPIKeyEditField.Value;
            query.channelID = app.ChannelIDEditField.Value;
            query.startHour = hours(str2double(app.StartHourDropDown.Value));
            query.startMinute = minutes(str2double(app.MinDropDown.Value));
            query.dmult = app.plotDuration.Value;
            query.lmult = app.plotLengthofComparison.Value;
            
            % build the start date with date, minutes, hours and am/PM
            queryRecent.startDate = app.StartDateDatePicker.Value + ...
                query.startHour + query.startMinute;
            
            if app.AMPMSwitch.Value == "PM"
                queryRecent.startDate = queryRecent.startDate + hours(12);
            end
            
            queryRecent.endDate = queryRecent.startDate + getCompareDuration(app);
            query.fieldsList = {};
            
            queryDisplayFields = enumerateSelectedFields(app);
            
            % build the fields list for ThingSpeak Read
            for i= 1:8
                if queryDisplayFields(i) > 0
                    query.fieldsList = [query.fieldsList, i];
                end
            end
            query.fieldsList = cell2mat(query.fieldsList);
            
            
            queryOld.startDate = queryRecent.startDate - getCompareWidth(app);
            queryOld.endDate = queryOld.startDate + getCompareDuration(app);
            
        end
        
        
        function myData = getDataFromQuery(app, query,queryStartEnd)
            %   getDataFromQueryRecent()   get the recent data.
            %   data = getDataFromQueryRecent(app, queryRecent) is a the
            %   most recent data.  Requires buildQueryFromInputs to get
            %   startDate, endDate, fieldsList, channelID, and APIKey.
            %
            try
                myData = thingSpeakRead(query.channelID, ...
                    'ReadKey', query.APIKey, ...
                    'DateRange',[queryStartEnd.startDate queryStartEnd.endDate], ...
                    'Fields', query.fieldsList, ...
                    'OutputFormat','Timetable');
                
            catch readingError
                myData = timetable();
                uialert(app.IoTDataExplorerForThingSpeakUIFigure,readingError.identifier,"Data error for that time interval");
                return
            end
            
            if isempty(myData)
                uialert(app.IoTDataExplorerForThingSpeakUIFigure,"No Data for that time interval","Pick some different data");
                myData = timetable();
                return
            end
            if height(myData) >= 8000
                app.StatusLabel.Text = "Read limit reached. Duration may be shorter than expected";
            end
            % Use retime if the user has selected that option
            if app.RetimeDropDown.Value ~= "Raw"
                myData = retime(myData,app.RetimeDropDown.Value,'linear');
            end
        end
        
        function visualizeData(app,recentData, oldData, queryInfo)
            %   visualizeData() display the data.
            %   visualizeData(app,recentData, oldData) uses a the recentData and oldData to
            %   plot the data in a tiled layot depending on the number of
            %   fields selected.
            
            dataDisplayFields = enumerateSelectedFields(app);
            
            if sum(dataDisplayFields) > width(oldData)
                uialert(app.IoTDataExplorerForThingSpeakUIFigure,"Not enough Fields selected","Pick a different channel or different fields.");
            end
            
            % Check to make sure the earlier functions produced data.
            if width(recentData) == 0
                return
            end
            if width(oldData) == 0
                return
            end
            
            t = tiledlayout(app.RightPanel,sum(dataDisplayFields),1,'tilespacing','none');
            
            % Change  data to elapsed time
            elapsedRecent = recentData.Timestamps - recentData.Timestamps(1) + ...
                queryInfo.startHour + queryInfo.startMinute;
            
            elapsedOld = oldData.Timestamps-oldData.Timestamps(1) + ...
                queryInfo.startHour + queryInfo.startMinute;
            
            % Determine which set is shortest.
            minLength = min(height(oldData),height(recentData));
            
            myTile = 0;
            for index = 1:8
                if dataDisplayFields(index)>0
                    myTile = myTile + 1;
                    myAxes = nexttile(t);
                    plotVar = recentData.(myTile);
                    plot(myAxes,elapsedRecent(1:minLength),plotVar(1:minLength),'-o','MarkerSize',2); %#<ADMTHDINV>
                    
                    if app.CompareLengthDropDown.Value ~= minutes(0) % minutes(0) is the setting for don't show the old data
                        hold(myAxes,"on");
                        plotVar2=oldData.(myTile);
                        plot(myAxes,elapsedOld(1:minLength),plotVar2(1:minLength),'-*','MarkerSize',2);
                    end
                    
                    title(myAxes,recentData.Properties.VariableNames(myTile));
                    
                    if myTile > 1
                        set (myAxes,'xtick',[]);
                        legend(myAxes,["Recent" ,"Old"]);
                    else
                        legend(myAxes,{app.legendLabel1, app.legendLabel2});
                    end
                end
            end
        end
        
        function compareDuration = getCompareDuration(app)
            % Determine the length in time of the data analysis for each
            % window from the GUI inputs.
            dmult = app.plotDuration.Value;
            compareDuration = app.DurationDropDown.Value * dmult;
        end
        
        function legendLabel = getLegendLabel(app,startDate)
            % Format the legendLabel based on the start data and duration
            % selected.
            dmult = app.plotDuration.Value;
            switch app.DurationDropDown.Value
                case minutes(1)
                    legendLabel = string(dmult) + ' Minutes on ' + string(startDate);
                case hours(1)
                    legendLabel = string(dmult) + ' Hours on ' + string(startDate);
                case hours(24)
                    legendLabel = string(dmult) + ' Days on ' + string(startDate);
                case days(7)
                    legendLabel = string(dmult) + ' Weeks on ' + string(startDate);
                case days(365)
                    legendLabel = string(dmult) + ' Years on ' + string(startDate);
            end
            if app.RetimeDropDown.Value ~= "Raw"
                legendLabel = legendLabel  + " " + string(app.RetimeDropDown.Value);
            end
        end
        
        function compareWidth = getCompareWidth(app)
            % Determine the difference in time from the recent data to the
            % older data from the GUI inputs.
            lmult = app.plotLengthofComparison.Value;
            compareWidth = app.CompareLengthDropDown.Value * lmult;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This fucntion sets the startup items for the duration and
            % compare dropdowns  If you put them in the items for the
            % object, they will not evaluate, i.e. you will get a string
            % minutes(1) etc.
            
            app.DurationDropDown.ItemsData = [minutes(1), hours(1), days(1), days(7)];
            app.CompareLengthDropDown.ItemsData = [minutes(0),minutes(1),hours(1), days(1), days(7),days(365)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IoTDataExplorerForThingSpeakUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {511, 511};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {241, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            % Determine the user selections
            [query,queryRecent,queryOld] = buildQueryFromInputs(app);
            
            % Retrieve data from ThingSpeak based on the selections
            recentData = getDataFromQuery(app,query,queryRecent);
            oldData = getDataFromQuery(app,query,queryOld);
            
            app.legendLabel1 = getLegendLabel(app,queryRecent.startDate);
            app.legendLabel2 = string(queryOld.startDate);
            
            % Plot the data
            visualizeData(app,recentData, oldData, query)
            
        end

        % Callback function
        function SelectChannelDropDownValueChanged(app, event)
            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: OutputtoWorkspaceButton
        function OutputtoWorkspaceButtonPushed(app, event)
            % Open a UI window to save the data to workspace variables.
            [query,queryRecent,queryOld] = buildQueryFromInputs(app);
            recentData = getDataFromQuery(app,query,queryRecent);
            oldData = getDataFromQuery(app,query,queryOld);
            
            labels = {'Save recent data to timetable named:' 'Save old data to timetable named:'};
            vars = {'oldData','recentData'};
            values = {recentData,oldData};
            export2wsdlg(labels,vars,values);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IoTDataExplorerForThingSpeakUIFigure = uifigure('Visible', 'off');
            app.IoTDataExplorerForThingSpeakUIFigure.Name = 'ThingSpeak Data Explorer';
            app.IoTDataExplorerForThingSpeakUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.IoTDataExplorerForThingSpeakUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.QuitButton = uibutton(app.LeftPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.StartDateDatePickerLabel = uilabel(app.LeftPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.LeftPanel);
            app.StartDateDatePicker.Value = datetime([2020 3 18]);
            app.DurationDropDownLabel = uilabel(app.LeftPanel);
            app.DurationDropDownLabel.Text = 'Duration';
            app.DurationDropDown = uidropdown(app.LeftPanel);
            app.DurationDropDown.Items = {'Minute', 'Hour', 'Day', 'Week'};
            app.DurationDropDown.Value = 'Day';
            app.CompareLengthDropDownLabel = uilabel(app.LeftPanel);
            app.CompareLengthDropDownLabel.Text = 'Compare Length';
            app.CompareLengthDropDown = uidropdown(app.LeftPanel);
            app.CompareLengthDropDown.Items = {'None', 'Minute', 'Hour', 'Day', 'Week', 'Year'};
            app.CompareLengthDropDown.Value = 'Week';
            app.ChannelIDEditFieldLabel = uilabel(app.LeftPanel);
            app.ChannelIDEditFieldLabel.Text = 'Channel ID';
            app.ChannelIDEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ChannelIDEditField.ValueDisplayFormat = '%.0f';
            app.ChannelIDEditField.Value = 38629;
            app.ReadAPIKeyEditFieldLabel = uilabel(app.LeftPanel);
            app.ReadAPIKeyEditFieldLabel.Text = 'ReadAPIKey';
            app.ReadAPIKeyEditField = uieditfield(app.LeftPanel, 'text');
            app.StartHourDropDownLabel = uilabel(app.LeftPanel);
            app.StartHourDropDownLabel.Text = 'Start Hour';
            app.StartHourDropDown = uidropdown(app.LeftPanel);
            app.StartHourDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.StartHourDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.StartHourDropDown.Value = '0';
            app.XEditField_2Label = uilabel(app.LeftPanel);
            app.XEditField_2Label.Text = 'X';
            app.plotDuration = uieditfield(app.LeftPanel, 'numeric');
            app.plotDuration.Limits = [1 365];
            app.plotDuration.Value = 1;
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.plotLengthofComparison = uieditfield(app.LeftPanel, 'numeric');
            app.plotLengthofComparison.Limits = [1 365];
            app.plotLengthofComparison.Value = 1;
            app.RetimeDropDown = uidropdown(app.LeftPanel);
            app.RetimeDropDown.Items = {'Raw', 'minutely', 'hourly', 'daily', 'weekly', 'monthly'};
            app.RetimeDropDown.Value = 'Raw';
            app.RetimeDropDownLabel = uilabel(app.LeftPanel);
            app.RetimeDropDownLabel.Text = 'Retime';
            app.AMPMSwitchLabel = uilabel(app.LeftPanel);
            app.AMPMSwitchLabel.Text = 'AM/PM';
            app.AMPMSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AMPMSwitch.Items = {'AM', 'PM'};
            app.AMPMSwitch.Value = 'AM';
            app.MinDropDownLabel = uilabel(app.LeftPanel);
            app.MinDropDownLabel.Text = 'Min';
            app.MinDropDown = uidropdown(app.LeftPanel);
            app.MinDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.MinDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.MinDropDown.Value = '0';
            app.OutputtoWorkspaceButton = uibutton(app.LeftPanel, 'push');
            app.OutputtoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @OutputtoWorkspaceButtonPushed, true);
            app.OutputtoWorkspaceButton.Text = 'Output to Workspace';
            app.F1CheckBox = uicheckbox(app.LeftPanel);
            app.F1CheckBox.Text = 'F1';
            app.F1CheckBox.Value = true;
            app.F2CheckBox = uicheckbox(app.LeftPanel);
            app.F2CheckBox.Text = 'F2';
            app.F3CheckBox = uicheckbox(app.LeftPanel);
            app.F3CheckBox.Text = 'F3';
            app.F4CheckBox = uicheckbox(app.LeftPanel);
            app.F4CheckBox.Text = 'F4';
            app.F5CheckBox = uicheckbox(app.LeftPanel);
            app.F5CheckBox.Text = 'F5';
            app.F6CheckBox = uicheckbox(app.LeftPanel);
            app.F6CheckBox.Text = 'F6';
            app.F7CheckBox = uicheckbox(app.LeftPanel);
            app.F7CheckBox.Text = 'F7';
            app.F8CheckBox = uicheckbox(app.LeftPanel);
            app.F8CheckBox.Text = 'F8';
            app.RightPanel = uipanel(app.GridLayout);
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.IoTDataExplorerForThingSpeakUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IoTDataExplorerForThingSpeakUsingMATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IoTDataExplorerForThingSpeakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IoTDataExplorerForThingSpeakUIFigure)
        end
    end
end